classdef Interfaz_pbl6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RecordarUsuarioCheckBox        matlab.ui.control.CheckBox
        INSTRUCCIONESButton            matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        Label                          matlab.ui.control.Label
        Lacontraseadebealmenoscontener8caracteresconLabel  matlab.ui.control.Label
        INICIODESESINLabel             matlab.ui.control.Label
        IniciarsesinButton             matlab.ui.control.StateButton
        ContraseaEditField             matlab.ui.control.EditField
        ContraseaEditFieldLabel        matlab.ui.control.Label
        NombredeusuarioEditField       matlab.ui.control.EditField
        NombredeusuarioEditFieldLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        Image2                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        RealPassword % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: IniciarsesinButton
        function IniciarsesinButtonValueChanged(app, event)
        while true
            % Obtener la contraseña real
        password = app.RealPassword;

        % Verificar si la contraseña cumple con los criterios
        if length(password) >= 8 && any(isstrprop(password, 'digit')) && any(isstrprop(password, 'upper'))
            datosdelpaciente;
        
         break; % Salir del bucle si se cumplen los criterios
        else
        % Mostrar mensaje de error si la contraseña no cumple con los criterios
        f = msgbox("Contraseña incorrecta. La contraseña debe tener al menos 8 caracteres, contener al menos un dígito y una letra mayúscula.", "Error");

        % Aquí puedes solicitar que se introduzca una nueva contraseña.
        % Por ejemplo, podrías usar un cuadro de entrada para una nueva contraseña:
        prompt = {'Introduce la nueva contraseña:'};
        dlgtitle = 'Nueva Contraseña';
        dims = [1 35];
        definput = {''};
        answer = inputdlg(prompt, dlgtitle, dims, definput);

        % Si se cancela la entrada de la nueva contraseña, salir del bucle
        if isempty(answer)
            break;
        else
            app.RealPassword = answer{1}; % Actualizar la contraseña en la aplicación
        end
        end
        end
            %Package App hay que hacer cuando se termine la interfaz
        end

        % Button pushed function: INSTRUCCIONESButton
        function INSTRUCCIONESButtonPushed(app, event)
            % Especifica el nombre del archivo PDF
    pdfFileName = '2.Instrucciones de uso_GlaucoTech.pdf';
    
    % Obtén la ruta completa al archivo PDF
    pdfFilePath = fullfile(fileparts(mfilename('fullpath')), pdfFileName);
    
    % Abre el archivo PDF en el navegador web predeterminado
    web(pdfFilePath, '-browser');
        end

        % Value changed function: ContraseaEditField
        function ContraseaEditFieldValueChanged(app, event)
            value = app.ContraseaEditField.Value;
            % Guardar la contraseña real cuando se establece por primera vez
        if isempty(app.RealPassword)
            app.RealPassword = value;
        end
        
        % Generar una cadena de asteriscos de la misma longitud que la contraseña
        maskedPassword = repmat('*', 1, numel(value));

        % Mostrar la contraseña enmascarada en el campo de contraseña
        app.ContraseaEditField.Value = maskedPassword;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Fondos verdes1.jpg';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.NombredeusuarioEditFieldLabel = uilabel(app.UIFigure);
            app.NombredeusuarioEditFieldLabel.Text = 'Nombre de usuario:';
            app.NombredeusuarioEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.UIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña:';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaEditField.ValueChangedFcn = createCallbackFcn(app, @ContraseaEditFieldValueChanged, true);
            app.IniciarsesinButton = uibutton(app.UIFigure, 'state');
            app.IniciarsesinButton.ValueChangedFcn = createCallbackFcn(app, @IniciarsesinButtonValueChanged, true);
            app.IniciarsesinButton.Text = 'Iniciar sesión';
            app.INICIODESESINLabel = uilabel(app.UIFigure);
            app.INICIODESESINLabel.Text = 'INICIO DE SESIÓN';
            app.Lacontraseadebealmenoscontener8caracteresconLabel = uilabel(app.UIFigure);
            app.Lacontraseadebealmenoscontener8caracteresconLabel.Text = '* la contraseña debe estar compuesta por 8 caracteres, con al menos una letra mayúscula y un número ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'WhatsApp_Image_2024-05-16_at_09.37.41-removebg-preview.png';
            app.INSTRUCCIONESButton = uibutton(app.UIFigure, 'push');
            app.INSTRUCCIONESButton.ButtonPushedFcn = createCallbackFcn(app, @INSTRUCCIONESButtonPushed, true);
            app.INSTRUCCIONESButton.Icon = 'instrucciones icono.png';
            app.INSTRUCCIONESButton.Text = 'INSTRUCCIONES';
            app.RecordarUsuarioCheckBox = uicheckbox(app.UIFigure);
            app.RecordarUsuarioCheckBox.Text = '      Recordar Usuario';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_pbl6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end