classdef datosdelpaciente < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VALIDEZDELAIMAGENTextArea       matlab.ui.control.TextArea
        VALIDEZDELAIMAGENTextAreaLabel  matlab.ui.control.Label
        IMAGENLabel                     matlab.ui.control.Label
        IDdelpacienteEditField_2        matlab.ui.control.EditField
        IDdelpacienteEditField_2Label   matlab.ui.control.Label
        CentrosanitarioEditField        matlab.ui.control.EditField
        CentrosanitarioEditFieldLabel   matlab.ui.control.Label
        CargardatosButton               matlab.ui.control.Button
        CargarimagenButton              matlab.ui.control.Button
        SiguienteButton                 matlab.ui.control.Button
        FechadeldiagnsticoDatePicker    matlab.ui.control.DatePicker
        FechadeldiagnsticoDatePickerLabel  matlab.ui.control.Label
        EdadEditField                   matlab.ui.control.NumericEditField
        EdadEditFieldLabel              matlab.ui.control.Label
        IDdelordenadorEditField         matlab.ui.control.EditField
        IDdelordenadorEditFieldLabel    matlab.ui.control.Label
        DATOSDELPACIENTELabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
     properties (Access = public)
         ImageData uint8 % Description
         
     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)

     % Abrir cuadro de diálogo de selección de archivos
     [archivo, carpeta] = uigetfile({'*.jpg'}, 'Seleccionar imagen');
 
        % Construir la ruta completa de la imagen seleccionada
         %rutaImagen = fullfile(carpeta, archivo);
         app.ImageData = imread(fullfile(carpeta, archivo));
         % Mostrar la imagen en un componente UIAxes
         imshow(app.ImageData, 'Parent', app.UIAxes);  

         % CARGAR LA VALIDEZ DE LA IMAGEN
        T=readtable('T_revisadaCaracteristicas.csv');
        T.image=[];
        T.quality = cellstr(num2str(T.quality));

        % Convert the values in the 'Quality' column
        for i = 1:height(T)
            if T.quality{i} == '1'
                T.quality{i} = 'Bajo contraste';
            elseif T.quality{i} == '2'
                T.quality{i} = 'Desenfoque';

            elseif T.quality{i} == '3'
                T.quality{i} = 'Ruido';

            elseif T.quality{i} == '4'
                T.quality{i} = 'Buena calidad';
            end
        end
        %ENTRENAMIENTO
        p = 0.2;
        cv_out = cvpartition(T.quality, 'HoldOut', p, 'Stratify', true);
        T_train = T(cv_out.training, :);
        mdl_final = fitcecoc(T_train, 'quality');

        %MODELO TEST
        T_cargada_carac=table; 
        imagen=app.ImageData;
            entropia = entropy((imagen));
            I = double(rgb2gray(imagen));
            Laplaziarra=[0 1 0; 1 -4 1; 0 1 0]; 
            L = imfilter(double(imagen),Laplaziarra, 'conv'); 
            contraste = std2(imagen);  
            laplace_med=mean(abs(L(:)));

        T_cargada_carac=addvars(T_cargada_carac,contraste, entropia, laplace_med,'NewVariableNames', {'Contraste', 'Entropia','Laplace_media'});
        rng(4);
        Y_pred_1 = predict(mdl_final, T_cargada_carac);
        app.VALIDEZDELAIMAGENTextArea.Value =Y_pred_1;

if Y_pred_1 ~="Buena calidad"
    f = msgbox("Introduzca otra imagen ","Error");
end 

        
        end

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
            resultadosdelpaciente;
        end

        % Button pushed function: CargardatosButton
        function CargardatosButtonPushed(app, event)
            % Verificar si todos los campos necesarios están completos
    if ~isempty(app.CentrosanitarioEditField.Value) && ~isempty(app.FechadeldiagnsticoDatePicker.Value) && ~isempty(app.IDdelpacienteEditField_2.Value) && ~isempty(app.EdadEditField.Value) && ~isempty(app.IDdelordenadorEditField.Value) 
        if length(app.IDdelpacienteEditField_2.Value) == 4 && isstrprop(app.IDdelpacienteEditField_2.Value(1), 'upper') && any(isstrprop(app.IDdelpacienteEditField_2.Value(2:4), 'digit')) && length(app.IDdelordenadorEditField.Value) ==3 && any(isstrprop(app.IDdelordenadorEditField.Value,'digit'))
        app.CargarimagenButton.Enable = 'on';
       
        
        else
        % Alguno de los IDs no cumple con el formato, mostrar mensaje de error
        f = msgbox("Los datos son incorrectos ","Error");
       
        app.CargarimagenButton.Enable = 'off';
        end
    else
    % Alguno de los campos está vacío, mostrar mensaje de error
    f = msgbox("Por favor, complete todos los campos ","Incompleto");
   
    app.CargarimagenButton.Enable = 'off';
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'WhatsApp_Image_2024-05-16_at_09.37.41-removebg-preview.png';
            app.DATOSDELPACIENTELabel = uilabel(app.UIFigure);
            app.DATOSDELPACIENTELabel.Text = 'DATOS DEL PACIENTE';
            app.IDdelordenadorEditFieldLabel = uilabel(app.UIFigure);
            app.IDdelordenadorEditFieldLabel.Text = 'ID del ordenador';
            app.IDdelordenadorEditField = uieditfield(app.UIFigure, 'text');
            app.EdadEditFieldLabel = uilabel(app.UIFigure);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.UIFigure, 'numeric');
            app.FechadeldiagnsticoDatePickerLabel = uilabel(app.UIFigure);
            app.FechadeldiagnsticoDatePickerLabel.Text = 'Fecha del diagnóstico';
            app.FechadeldiagnsticoDatePicker = uidatepicker(app.UIFigure);
            app.SiguienteButton = uibutton(app.UIFigure, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.CargarimagenButton = uibutton(app.UIFigure, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Enable = 'off';
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.CargardatosButton = uibutton(app.UIFigure, 'push');
            app.CargardatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargardatosButtonPushed, true);
            app.CargardatosButton.Text = 'Cargar datos';
            app.CentrosanitarioEditFieldLabel = uilabel(app.UIFigure);
            app.CentrosanitarioEditFieldLabel.Text = 'Centro sanitario';
            app.CentrosanitarioEditField = uieditfield(app.UIFigure, 'text');
            app.IDdelpacienteEditField_2Label = uilabel(app.UIFigure);
            app.IDdelpacienteEditField_2Label.Text = 'ID del paciente';
            app.IDdelpacienteEditField_2 = uieditfield(app.UIFigure, 'text');
            app.IMAGENLabel = uilabel(app.UIFigure);
            app.IMAGENLabel.Text = 'IMAGEN';
            app.VALIDEZDELAIMAGENTextAreaLabel = uilabel(app.UIFigure);
            app.VALIDEZDELAIMAGENTextAreaLabel.Text = 'VALIDEZ DE LA IMAGEN:';
            app.VALIDEZDELAIMAGENTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = datosdelpaciente

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end