classdef resultadosdelpaciente < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MostrarparmetrosButton       matlab.ui.control.Button
        PRESENCIADEGLAUCOMATextArea  matlab.ui.control.TextArea
        PRESENCIADEGLAUCOMATextAreaLabel  matlab.ui.control.Label
        CargarresultadosButton       matlab.ui.control.Button
        RESULTADOSLabel              matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        Label                        matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end


     properties (Access = public, Transient, NonCopyable)
        ImageData uint8 % Datos de la imagen cargada
        Caracteristicas
        Caracteristicas_calidad
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarresultadosButton
        function CargarresultadosButtonPushed(app, event)
    imshow(app.ImageData,'Parent', app.UIAxes4);
 % Llamar a la función para crear el ROI
    cropped_image = crearROI(app.ImageData);
    imshow(cropped_image, 'Parent', app.UIAxes);

   % Llamar a la función para segmentar el disco
    Disco_corregido = segmentarDisco(cropped_image);
    imshow(cropped_image, 'Parent', app.UIAxes2);
    hold(app.UIAxes2, 'on');
    [B, ~] = bwboundaries(Disco_corregido, 'noholes');
    for k = 1:length(B)
        boundary = B{k};
        fill(app.UIAxes2, boundary(:,2), boundary(:,1), 'r', 'FaceAlpha', 0.5, 'EdgeColor', 'none');
    end
    hold(app.UIAxes2, 'off');

    % Llamar a la función para segmentar la copa
    Cup_segmentado_final = segmentarCopa(cropped_image);
    imshow(cropped_image, 'Parent', app.UIAxes3);
    hold(app.UIAxes3, 'on');
    [B, ~] = bwboundaries(Cup_segmentado_final, 'noholes');
    for k = 1:length(B)
        boundary = B{k};
        fill(app.UIAxes3, boundary(:,2), boundary(:,1), 'r', 'FaceAlpha', 0.5, 'EdgeColor', 'none');
    end
    hold(app.UIAxes3, 'off');

    %APLICAR EL MODELO DE PREDICCIÓN


   app.Caracteristicas = Carac_Glaucoma(cropped_image,Disco_corregido,Cup_segmentado_final);
   Glaucoma=Modelo_glaucoma(app.Caracteristicas);
   app.PRESENCIADEGLAUCOMATextArea.Value=Glaucoma;


        end

        % Button pushed function: MostrarparmetrosButton
        function MostrarparmetrosButtonPushed(app, event)
            

            terceraApp = Parametros;
            terceraApp.Caracteristicas = app.Caracteristicas;
            terceraApp.Caracteristicas_calidad = app.Caracteristicas_calidad;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ROI')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Disco segmentado')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Copa segmentada')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Imagen original')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'WhatsApp_Image_2024-05-16_at_09.37.41-removebg-preview.png';
            app.RESULTADOSLabel = uilabel(app.UIFigure);
            app.RESULTADOSLabel.Text = 'RESULTADOS';
            app.CargarresultadosButton = uibutton(app.UIFigure, 'push');
            app.CargarresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarresultadosButtonPushed, true);
            app.CargarresultadosButton.Text = 'Cargar resultados';
            app.PRESENCIADEGLAUCOMATextAreaLabel = uilabel(app.UIFigure);
            app.PRESENCIADEGLAUCOMATextAreaLabel.Text = 'PRESENCIA DE GLAUCOMA:';
            app.PRESENCIADEGLAUCOMATextArea = uitextarea(app.UIFigure);
            app.MostrarparmetrosButton = uibutton(app.UIFigure, 'push');
            app.MostrarparmetrosButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarparmetrosButtonPushed, true);
            app.MostrarparmetrosButton.Text = 'Mostrar parámetros';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resultadosdelpaciente

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end